classdef VideoPlayerUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        FileMenu             matlab.ui.container.Menu
        LoadvideoMenu        matlab.ui.container.Menu
        Slider               matlab.ui.control.Slider
        SliderLabel          matlab.ui.control.Label
        PreviousframeButton  matlab.ui.control.Button
        NextframeButton      matlab.ui.control.Button
        PlayButton           matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        filepath % Description
        videoFile % Description
        status = 'stopped'% Description
        currentFrame
        lastFolder = pwd;
        myTimer
    end
    
    methods (Access = private)
        
        function results = UpdateUI(app,src,event)

            
            app.PlayButton.Text = 'Play';
            
            
            switch(app.status)
                case 'paused'
                    
                    I = app.videoFile.read(app.currentFrame);
                    imshow(I,'Parent',app.UIAxes);
                    text(app.UIAxes, 0, 0, [num2str(app.currentFrame) '/' num2str(app.videoFile.NumFrames)],'fontsize',14);
                    app.Slider.Value = app.currentFrame;
            
                    if ( ~isempty(app.videoFile))
                        if (app.currentFrame < app.videoFile.NumFrames)
                            app.NextframeButton.Enable = true;
                        else
                            app.NextframeButton.Enable = false;
                        end
                        if (app.currentFrame > 1)
                            app.PreviousframeButton.Enable = true;
                        else
                            app.PreviousframeButton.Enable = false;
                        end
                    end
                case 'playing'
                    app.PlayButton.Text = 'Pause';
                    
                    I = app.videoFile.read(app.currentFrame);
                    imshow(I,'Parent',app.UIAxes);
                    text(app.UIAxes, 0, 0, [num2str(app.currentFrame) '/' num2str(app.videoFile.NumFrames)],'fontsize',14);
                    app.Slider.Value = app.currentFrame;
                    
                    app.currentFrame = app.currentFrame + 1;
                    if ( app.currentFrame > app.videoFile.NumFrames)
                        app.currentFrame = app.videoFile.NumFrames;
                        app.status = 'paused';
                    end
                    app.NextframeButton.Enable = false;
                    app.PreviousframeButton.Enable = false;
                otherwise
                    app.NextframeButton.Enable = false;
                    app.PreviousframeButton.Enable = false;
            end

                
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.myTimer = timer('Period',0.1,...
                'ExecutionMode', 'fixedSpacing', ...
                'TasksToExecute', Inf);
            app.myTimer.TimerFcn = @app.UpdateUI;
            app.myTimer.start();
        end

        % Menu selected function: LoadvideoMenu
        function LoadvideoMenuSelected(app, event)

            [f, p] = uigetfile(fullfile(app.lastFolder,'*.*'), 'Pick a video file');
            if ( isempty(f))
                return
            end
            
            app.lastFolder = p;
            app.filepath = fullfile(p,f);
            
            try
                app.videoFile = VideoReader(app.filepath); 
                app.Slider.Value = 1;
                app.Slider.Limits = [1 app.videoFile.NumFrames];
                app.currentFrame = 1;
                app.status = 'paused';

            catch ME
                ME.getReport
                msgbox("Error loading file");
            end
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            switch(app.status)
                case 'playing'
                    app.status = 'paused';
                 otherwise
                    app.status = 'playing';
            end
        end

        % Button pushed function: NextframeButton
        function NextframeButtonPushed(app, event)
            app.currentFrame = app.currentFrame + 1;
        end

        % Button pushed function: PreviousframeButton
        function PreviousframeButtonPushed(app, event)
            app.currentFrame = app.currentFrame - 1;
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            app.currentFrame = round(changingValue);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            if ( ~isempty(app.myTimer))
                app.myTimer.stop();
            end
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadvideoMenu = uimenu(app.FileMenu);
            app.LoadvideoMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadvideoMenuSelected, true);
            app.LoadvideoMenu.Text = 'Load video...';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.NextframeButton = uibutton(app.UIFigure, 'push');
            app.NextframeButton.ButtonPushedFcn = createCallbackFcn(app, @NextframeButtonPushed, true);
            app.NextframeButton.Text = 'Next frame';
            app.PreviousframeButton = uibutton(app.UIFigure, 'push');
            app.PreviousframeButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousframeButtonPushed, true);
            app.PreviousframeButton.Text = 'Previous frame';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VideoPlayerUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end